classdef EEG2ET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        EEGIndexDropDownLabel   matlab.ui.control.Label
        EEGIndexDropDown        matlab.ui.control.DropDown
        PercentileSpinnerLabel  matlab.ui.control.Label
        PercentileSpinner       matlab.ui.control.Spinner
        DoneButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
        memorization % Description
        aw_index
        workload
        index_sample
    end
    
    methods (Access = public)
        
        function updateplot(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        app.CallingApp=mainapp;    
        memorization=[];
            aw_index=[];
            workload=[];
            index_sample=[];
            step_size=1*app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
            window_size=round(step_size/2);
            for i_sample=1+window_size:step_size:size(app.CallingApp.trials{app.CallingApp.index}.EEG,2)-window_size
                memorization(end+1) = bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[4 8]) / ...
                    bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[1 45]);
                aw_index(end+1) = bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[4 8]) / ...
                    bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[1 45]);
                workload(end+1) = bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[4 8]) / ...
                    bandpower(app.CallingApp.trials{app.CallingApp.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.CallingApp.trials{app.CallingApp.index}.EEG_fs,[1 45]);
                index_sample(end+1)=i_sample;
            end
            app.memorization=memorization;
            app.aw_index=aw_index;
            app.workload=workload;
            app.index_sample=index_sample;
            
        end

        % Value changed function: EEGIndexDropDown
        function EEGIndexDropDownValueChanged(app, event)
            value = app.EEGIndexDropDown.Value;
            idx=find(strcmp(app.EEGIndexDropDown.Items,value));
            step_size=1*app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
            window_size=round(step_size/2);
            eeg_time=[1:size(app.CallingApp.trials{app.CallingApp.index}.EEG,2)]/app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
            et_time=[1:size(app.CallingApp.trials{app.CallingApp.index}.ET,2)]/app.CallingApp.trials{app.CallingApp.index}.ET_fs;
            [height, width, depth]=size(app.CallingApp.I);
            if idx==1
                threshhold = prctile(app.memorization,app.PercentileSpinner.Value)
                selected_indeces=app.index_sample(find(app.memorization>threshhold));
                selected_times=[selected_indeces-window_size; selected_indeces+window_size]/app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
                
                ET=[];for i=1:size(selected_times,2)
                    ET=cat(2,app.CallingApp.trials{app.CallingApp.index}.ET(:,et_time>=selected_times(1,i) & et_time<=selected_times(2,i)), ET);
                end
                mycolor=[0 0.4470 0.7410];
            elseif idx==2
                threshhold = prctile(app.aw_index,app.PercentileSpinner.Value)
                selected_indeces=app.index_sample(find(app.aw_index>threshhold));
                selected_times=[selected_indeces-window_size; selected_indeces+window_size]/app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
                
                ET=[];for i=1:size(selected_times,2)
                    ET=cat(2,app.CallingApp.trials{app.CallingApp.index}.ET(:,et_time>=selected_times(1,i) & et_time<=selected_times(2,i)), ET);
                end
                mycolor=[0.8500 0.3250 0.0980];
            elseif idx==3
                threshhold = prctile(app.workload,app.PercentileSpinner.Value)
                selected_indeces=app.index_sample(find(app.workload>threshhold));
                selected_times=[selected_indeces-window_size; selected_indeces+window_size]/app.CallingApp.trials{app.CallingApp.index}.EEG_fs;
                
                ET=[];for i=1:size(selected_times,2)
                    ET=cat(2,app.CallingApp.trials{app.CallingApp.index}.ET(:,et_time>=selected_times(1,i) & et_time<=selected_times(2,i)), ET);
                end
                mycolor=[0.9290 0.6940 0.1250];
            end
            
            imshow(app.CallingApp.I, 'parent', app.UIAxes),hold(app.UIAxes,'on');
            scatter(app.UIAxes, mean(ET([1 4],:)*width), mean(ET([2 5],:))*height,[],mycolor, 'filled', 'MarkerFaceAlpha', 0.4);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.EEG2ETMenu.Enable = 'on';
            delete(app)
    
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.CallingApp.EEG2ETMenu.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gaze Points')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.EEGIndexDropDownLabel = uilabel(app.UIFigure);
            app.EEGIndexDropDownLabel.Text = 'EEG Index';
            app.EEGIndexDropDown = uidropdown(app.UIFigure);
            app.EEGIndexDropDown.Items = {'Memorization', 'A-W', 'Workload'};
            app.EEGIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @EEGIndexDropDownValueChanged, true);
            app.EEGIndexDropDown.Value = 'Memorization';
            app.PercentileSpinnerLabel = uilabel(app.UIFigure);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.UIFigure);
            app.PercentileSpinner.Step = 5;
            app.PercentileSpinner.Limits = [-100 100];
            app.PercentileSpinner.Value = 70;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG2ET(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end