classdef ROImanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ROIsDropDownLabel  matlab.ui.control.Label
        ROIsDropDown       matlab.ui.control.DropDown
        AddButton          matlab.ui.control.Button
        RemoveButton       matlab.ui.control.Button
        DoneButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % Description
        FullImageUIAxes
        ROI
        listROI={}
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.listROI=app.CallingApp.trials{app.CallingApp.index}.ROIs;
            app.FullImageUIAxes = axes(app.UIFigure, 'Units', 'Pixels', 'Position',[1,148,590,328], 'XTick', [], 'YTick', []);
            imshow(app.CallingApp.I, 'parent', app.FullImageUIAxes),hold(app.FullImageUIAxes,'on');
            app.ROI = drawrectangle(app.FullImageUIAxes, 'Position', [100 ,100, 100, 100], 'LineWidth', [1], 'Color', [1 0 0]);
            
            [height, width, depth]=size(app.CallingApp.I);
            
            if isempty(app.listROI)
                ET_cleaned=app.CallingApp.preprocessET();
                X=ET_cleaned(1:2,:)';
                myx=X(:,1);
                myy=X(:,2);
                X(myx>1 | myx<0,:)=[];
                X(myy>1 | myy<0,:)=[];
                X(:,1)=X(:,1)*width;
                X(:,2)=X(:,2)*height;
                clust=[];for i=1:10
                    clust(:,i) = kmeans(X,i);
                end
                va = evalclusters(X,clust,'CalinskiHarabasz')
                kept_clust=clust(:,va.OptimalK)
                colormap=jet(va.OptimalK)
                for i=1:va.OptimalK
                    %                 scatter(app.FullImageUIAxes,X(kept_clust==i,1),X(kept_clust==i,2),[],colormap(i,:),'filled'),hold(app.FullImageUIAxes,'on');
                    posx=min(X(kept_clust==i,1));
                    posy=min(X(kept_clust==i,2));
                    width=max(X(kept_clust==i,1))-posx;
                    height=max(X(kept_clust==i,2))-posy;
                    rectangle(app.FullImageUIAxes,'Position',[posx posy width height]);
                    temp=rectangle(app.FullImageUIAxes,'Position',[posx posy width height]);
                    app.listROI{end+1}=temp.Position;
                    app.ROIsDropDown.Items{end+1}=strcat('ROI:',num2str(i));
                    text(app.FullImageUIAxes,app.listROI{i}(1),app.listROI{i}(2),app.ROIsDropDown.Items{end});
                end
            else
                app.ROIsDropDown.Items={};
                for i_ROI=1:length(app.listROI)
                    app.ROIsDropDown.Items{end+1}=strcat('ROI:',num2str(i_ROI));
                    rectangle(app.FullImageUIAxes,'Position',app.listROI{i_ROI});
                    text(app.FullImageUIAxes,app.listROI{i_ROI}(1),app.listROI{i_ROI}(2),app.ROIsDropDown.Items{end});
                end
            end
                
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            rectangle(app.FullImageUIAxes,'Position',app.ROI.Position);
            app.ROIsDropDown.Items{end+1}=strcat('ROI:',num2str(length(app.ROIsDropDown.Items)+1))%strcat(num2str(app.ROI.Position(1)),',',app.ROI.Position(2))
            text(app.FullImageUIAxes,app.ROI.Position(1),app.ROI.Position(2),app.ROIsDropDown.Items{end});
            app.listROI{end+1}=app.ROI.Position;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.CallingApp.getROIlist(app.listROI);
            app.CallingApp.ROIManagementMenu.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ROIManagementMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            index=find(strcmp(app.ROIsDropDown.Items, app.ROIsDropDown.Value))
            app.ROIsDropDown.Items={};
            app.listROI(index)=[];
            imshow(app.CallingApp.I, 'parent', app.FullImageUIAxes);
            for i_ROI=1:length(app.listROI)
                app.ROIsDropDown.Items{end+1}=strcat('ROI:',num2str(i_ROI));
                rectangle(app.FullImageUIAxes,'Position',app.listROI{i_ROI});
                text(app.FullImageUIAxes,app.listROI{i_ROI}(1),app.listROI{i_ROI}(2),app.ROIsDropDown.Items{end});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ROIsDropDownLabel = uilabel(app.UIFigure);
            app.ROIsDropDownLabel.Text = 'ROIs';
            app.ROIsDropDown = uidropdown(app.UIFigure);
            app.ROIsDropDown.Items = {};
            app.ROIsDropDown.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end