classdef ROIstatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        DoneButton  matlab.ui.control.Button
        UITable     matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Description
        listROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fix_list)
            app.CallingApp = mainapp;
            app.listROI=app.CallingApp.trials{app.CallingApp.index}.ROIs;
            %             i=1;
            my_colnames = {'TTFF' 'First Fixation Duration' 'Dwell Time' 'Revisit No' };
            my_rownames={};
            for i=1:length(app.listROI)
                my_rownames{i} = strcat('ROI ', num2str(i));
                posx = app.listROI{i}(1);
                posy = app.listROI{i}(2);
                width = app.listROI{i}(3);
                height = app.listROI{i}(4);
                XX = [posx posx+width posx+width posx];
                YY = [posy posy posy+height posy+height];
                my_fixingROI = inpolygon(fix_list.all(:,1)*size(app.CallingApp.I,2),fix_list.all(:,2)*size(app.CallingApp.I,1),XX,YY);
                fixinROI(i,:)= my_fixingROI;
                if sum(my_fixingROI)==0
                    TTFF(i)=-1;
                    FFD(i)=-1;
                    ReNo(i)=-1;
                    DT(i)=-1;
                else
                    idx = find(my_fixingROI==1);
                    TTFF(i)=fix_list.all(idx(1),5)/1000;
                    FFD(i)=fix_list.all(idx(1),7)/1000;
                    ReNo(i) = sum(diff([0;my_fixingROI])==1)-1; %
                    DT(i) = sum(fix_list.all(my_fixingROI,7))/1000;
                end
                
                %                 rectangle(app.UIAxes,'Position',[app.listROI{i}(1) app.listROI{i}(2) app.listROI{i}(3) app.listROI{i}(4)]),hold(app.UIAxes,'on');
                %                 plot(app.UIAxes,fix_list.all(1,2),fix_list.all(1,3),'ro')
%                 stem(app.UIAxes,fixinROI(i,:)),hold(app.UIAxes,'on');
            end
            imshow(app.CallingApp.I, 'parent', app.UIAxes),hold(app.UIAxes, 'on');
            ROIlist = app.CallingApp.trials{app.CallingApp.index}.ROIs;
            for i=1:length(ROIlist)
                rectangle(app.UIAxes,'Position',ROIlist{i})
                text(app.UIAxes,ROIlist{i}(1),ROIlist{i}(2),strcat('ROI: ',num2str(i)))
            end
%             stem(app.UIAxes,TTFF');
            app.UITable.ColumnName = my_colnames;%{'ROI1' 'ROI2' 'ROI3'};
            app.UITable.RowName = my_rownames;% {'ROI1' 'ROI2' 'ROI3'};
%             plot(app.UIAxes,fix_list.all(:,2),fix_list.all(:,3),'ro'),app.UIAxes.YLim=[0 size(app.CallingApp.I,1)],app.UIAxes.XLim=[0 size(app.CallingApp.I,2)];
            app.UITable.Data =[TTFF' FFD' DT' ReNo'];% {5 10 15 20 25};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ROIStatisticsMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.CallingApp.ROIStatisticsMenu.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIstatistics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end