classdef box_scanpath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FixationsDropDownLabel  matlab.ui.control.Label
        FixationsDropDown       matlab.ui.control.DropDown
        UIAxes                  matlab.ui.control.UIAxes
        OKButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        fixation_list
        trial
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fix_list, trial)
            app.CallingApp = mainapp;
            app.fixation_list=fix_list;
            app.trial=trial;
            app.FixationsDropDown.Items={};
            for i_trial=1:size(fix_list.all,1)
                app.FixationsDropDown.Items{end+1} = strcat('Fixation:',num2str(i_trial));
            end
        end

        % Value changed function: FixationsDropDown
        function FixationsDropDownValueChanged(app, event)
            value = app.FixationsDropDown.Value;
            my_index = find(strcmp(app.FixationsDropDown.Items, value));
            EEG_time = [1:size(app.trial.EEG,2)]/app.trial.EEG_fs;
            EEG_samples = EEG_time>=app.fixation_list.all(my_index,5)/1000 & EEG_time<=app.fixation_list.all(my_index,6)/1000;
            idx=find(EEG_samples);
            eeg_indices=app.CallingApp.calculateEEGIndices(min(idx),max(idx));
            X=categorical({'A-W Index', 'Memorization', 'Workload'});
            bar(app.UIAxes, X, [eeg_indices.alpha_asymmetry eeg_indices.memorization eeg_indices.workload]);
%             plot(app.UIAxes,app.trial.EEG(1,EEG_samples))
            %             stem(app.UIAxes,[app.fixation_list.all(my_index,5) app.fixation_list.all(my_index,6)])
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.ScanPathOptionsButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ScanPathOptionsButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FixationsDropDownLabel = uilabel(app.UIFigure);
            app.FixationsDropDownLabel.Text = 'Fixations';
            app.FixationsDropDown = uidropdown(app.UIFigure);
            app.FixationsDropDown.ValueChangedFcn = createCallbackFcn(app, @FixationsDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = box_scanpath(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end