classdef dashboard_DSI24 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FIleMenu               matlab.ui.container.Menu
        LoadRecordingMenu      matlab.ui.container.Menu
        EditMenu               matlab.ui.container.Menu
        TrialSegmentationMenu  matlab.ui.container.Menu
        HelpMenu               matlab.ui.container.Menu
        LogFileMenu            matlab.ui.container.Menu
        ToolsMenu              matlab.ui.container.Menu
        ROIManagementMenu      matlab.ui.container.Menu
        ROIStatisticsMenu      matlab.ui.container.Menu
        EEG2ETMenu             matlab.ui.container.Menu
        TrialsDropDownLabel    matlab.ui.control.Label
        TrialsDropDown         matlab.ui.control.DropDown
        LogTextAreaLabel       matlab.ui.control.Label
        LogTextArea            matlab.ui.control.TextArea
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        ScanPathOptionsButton  matlab.ui.control.Button
        UIAxes4_1              matlab.ui.control.UIAxes
        UIAxes4_2              matlab.ui.control.UIAxes
        UIAxes4_3              matlab.ui.control.UIAxes
        TimeSliderLabel        matlab.ui.control.Label
        TimeSlider             matlab.ui.control.Slider
        UIAxes5                matlab.ui.control.UIAxes
        PlayButton             matlab.ui.control.Button
        UIAxes6                matlab.ui.control.UIAxes
        UIAxes4_4              matlab.ui.control.UIAxes
        UIAxes4_5              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DialogApp
        trials={}; % Holds the segmeneted trials into a cell-array;
        index % Holds the index of the active trial;
        I
        last_plot
        last_gaze_plot
        PlayButtonCounter=0;
        ChannelOrder={'Fp1', 'F3', 'F7', 'C3', 'T3', 'P3', 'T5', 'O1', 'Fp2', 'F4', 'F8', 'C4', 'T4', 'P4', 'T6', 'O2', 'Fz', 'Cz', 'Pz'};
    end
    
    methods (Access = public)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%           GENERIC          %%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function trial_segmentation(app,path)
            myData = load_xdf(path);
            EEG_stream = []; ET_stream = []; MRK_stream = [];
            for i_stream = 1:size(myData,2) %Stream Idenditifaction (Correct Type and NOT empty)
                if strcmp(myData{1,i_stream}.info.type,'EEG') && (~isempty(myData{1, i_stream}.time_series))
                    EEG_stream = myData{1,i_stream};
                elseif strcmp(myData{1,i_stream}.info.type,'Eyetracker') && (~isempty(myData{1, i_stream}.time_series))
                    ET_stream = myData{1,i_stream};
                elseif strcmp(myData{1,i_stream}.info.name,'MyMarkerStream3') && (~isempty(myData{1, i_stream}.time_series))% && strcmp(myData{1,i_stream}.info.hostname,'DESKTOP-PP2KBHS')
                    MRK_stream = myData{1,i_stream};
                    %MOUSE_stream = myData{1,i_stream};
                elseif strcmp(myData{1,i_stream}.info.name,'MouseButtons') && (~isempty(myData{1, i_stream}.time_series))
                    MOUSE_stream = myData{1,i_stream};
                end
            end
            
            trials={};
            if isempty(EEG_stream) || isempty(ET_stream) || isempty(MRK_stream) || isempty(MOUSE_stream)
                disp('One of the streams was empty.')
            else
                for i=1:length(EEG_stream.info.desc.channels.channel)
                    recChannelORder{i}= EEG_stream.info.desc.channels.channel{i}.label;
                end
                for i=1:length(app.ChannelOrder)
                    idx=find(strcmp(app.ChannelOrder{i}, recChannelORder));
                    correctChannelOrder(i)=idx(1);
                end
                for i_marker = 1:size(MRK_stream.time_series,2)-1 % The last marker is the "end of trial"
                    time_start = MRK_stream.time_stamps(i_marker)
                    time_end = MRK_stream.time_stamps(i_marker+1)
                    eeg_pos_start = find(abs((EEG_stream.time_stamps-time_start))==min(abs(EEG_stream.time_stamps-time_start)));
                    et_pos_start = find(abs((ET_stream.time_stamps-time_start))==min(abs(ET_stream.time_stamps-time_start)));
                    mouse_pos_start = find(abs((MOUSE_stream.time_stamps-time_start))==min(abs(MOUSE_stream.time_stamps-time_start)));


                    eeg_pos_end = find(abs((EEG_stream.time_stamps-time_end))==min(abs(EEG_stream.time_stamps-time_end)));
                    et_pos_end = find(abs((ET_stream.time_stamps-time_end))==min(abs(ET_stream.time_stamps-time_end)));
                    mouse_pos_end = find(abs((MOUSE_stream.time_stamps-time_end))==min(abs(MOUSE_stream.time_stamps-time_end)));


                    trial.MOUSE = MOUSE_stream.time_series(:,mouse_pos_start:mouse_pos_end);
                    press_down=find(trial.MOUSE=="MouseButtonLeft pressed");
                    trial.MOUSE=trial.MOUSE(press_down)
                    trial.MOUSE_times = MOUSE_stream.time_stamps(:,mouse_pos_start:mouse_pos_end)-time_start;
                    trial.MOUSE_times =  trial.MOUSE_times(press_down)
                    trial.EEG = EEG_stream.time_series(:,eeg_pos_start:eeg_pos_end);
                    trial.ET = ET_stream.time_series(:,et_pos_start:et_pos_end);
                    trial.EEG_fs = round(EEG_stream.info.effective_srate);
                    [b,a]=butter(3, [1 45]/(trial.EEG_fs/2));
                    filtered_EEG=filtfilt(b,a,double(trial.EEG)')';
                    EEG_clean=wICA_denoise(filtered_EEG, round(size(filtered_EEG,1)*2/3), 0);
                    trial.EEG=EEG_clean(correctChannelOrder,:);
                    
                    
                    trial.ET_fs = round(ET_stream.info.effective_srate);
                    if strcmp(MRK_stream.time_series(i_marker),'EOE')
                        break;
                    elseif strcmp(MRK_stream.time_series(i_marker),'fixation_cross')
                        continue;
                    end
                    tempStr = split(MRK_stream.time_series(i_marker),'=');
                    Category = split(tempStr{1,1},':');
                    ID = split(tempStr{2,1},':');
                    Type = split(tempStr{3,1},':');
                    trial.Category = Category{2};
                    trial.ID = ID{2};
                    trial.Type = Type{2};
                    trial.ROIs={};
                    trials{end+1} = trial;
                end
            end
            app.trials=trials;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%            PLOTS          %%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updateImage(app, myax, myhold)
            myax.cla;
            imshow(app.I, 'parent', myax),hold(myax, myhold)
        end
        
        function updateGazeHeatmap(app)
            spatial_resolution=30;
            [height, width, depth]=size(app.I);
            eye_x=mean(app.trials{app.index}.ET([1,4],:))*width;
            eye_y=mean(app.trials{app.index}.ET([2,5],:))*height;
            [n, c]=hist3([eye_x;eye_y]','Ctrs',{0:round(width/spatial_resolution):width 0:round(height/spatial_resolution):height});
            updateImage(app, app.UIAxes2, 'on');
            
            h=imresize(double(n'),[height,width]);
            mycolormap=jet(30);
            imagesc(app.UIAxes2,max(h(:))-h,'AlphaData',0.3); colormap(app.UIAxes2,mycolormap(end:-1:1,:));set(app.UIAxes2,'CLim',[mean(h(:)), max(h(:))]);
        end
        function updateScanPath(app)
            updateImage(app, app.UIAxes, 'on')
            if ~isempty(app.trials{app.index}.ROIs)
                for i=1:length(app.trials{app.index}.ROIs)
                    rectangle(app.UIAxes,'Position',app.trials{app.index}.ROIs{i})
                    text(app.UIAxes,app.trials{app.index}.ROIs{i}(1),app.trials{app.index}.ROIs{i}(2),strcat('ROI: ',num2str(i)))
                end
            end
            [height, width, depth]=size(app.I);
            ET_cleaned=app.preprocessET();
            fixation_list=app.calculateFixationList(ET_cleaned);
            app.trials{app.index}.fixation_list=fixation_list;
            for i=1:size(fixation_list.duration_norm,1)
                plot(app.UIAxes, fixation_list.all(i,1)*width,fixation_list.all(i,2)*height,'--go','MarkerSize',fixation_list.duration_norm(i)*20),hold(app.UIAxes,'on')
                text(app.UIAxes, fixation_list.all(i,1)*width,fixation_list.all(i,2)*height,int2str(i),'FontSize',14, 'Color','green')
            end
            line(app.UIAxes,fixation_list.all(:,1)*width,fixation_list.all(:,2)*height,'Color','green'),
        end
        
        function plotTVEEGIndices(app)
            app.UIAxes4_1.cla;
            plot(app.UIAxes4_1, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.aw_index),hold(app.UIAxes4_1,'on')
            plot(app.UIAxes4_1, app.trials{app.index}.MOUSE_times,ones(1,length(app.trials{app.index}.MOUSE_times))*...
                (max(app.trials{app.index}.aw_index)),'rx')
            app.UIAxes4_2.cla;
            plot(app.UIAxes4_2, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.memorization)
            app.UIAxes4_3.cla;
            plot(app.UIAxes4_3, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.workload)
            
            app.UIAxes4_4.cla;
            plot(app.UIAxes4_4, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.valence)
            app.UIAxes4_5.cla;
            plot(app.UIAxes4_5, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.arousal)
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%             EEG            %%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function eeg_indices=calculateEEGIndices(app,EEG_time_start,EEG_time_end)
            if nargin<2
                EEG_time_start = 1;
                EEG_time_end = size(app.trials{app.index}.EEG,2);
            end
            alpha_asymmetry = (bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[8 13])-bandpower(app.trials{app.index}.EEG(9,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[8 13]));%/...
            %(bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[8 13])+bandpower(app.trials{app.index}.EEG(2,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[8 13]));
            memorization = bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[4 8]) / bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[1 45]);
            workload=bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[4 8])/bandpower(app.trials{app.index}.EEG(1,EEG_time_start:EEG_time_end),app.trials{app.index}.EEG_fs,[1 13]);
            eeg_indices.alpha_asymmetry=alpha_asymmetry;
            eeg_indices.workload=workload;
            eeg_indices.memorization=memorization;
        end
        
        function updateEEGMetrics(app)
            eeg_indices=calculateEEGIndices(app)
            X=categorical({'A-W Index', 'Memorization', 'Workload'})
            bar(app.UIAxes3, X, [eeg_indices.alpha_asymmetry eeg_indices.memorization eeg_indices.workload]);%,app.UIAxes3.YLim=[-1 1];
        end
        
        function calculateTVEEGMetric(app)
            memorization=[];
            aw_index=[];
            workload=[];
            index_sample=[];
            valence=[];
            val_left=[];
            val_right=[];
            arousal=[];
            step_size=100;%0.1*app.trials{app.index}.EEG_fs;
            window_size=100;%round(step_size/2);
            [b_alpha,a_alpha]=butter(3, [8 13]/(app.trials{app.index}.EEG_fs/2));
            
            left_alpha=filtfilt(b_alpha,a_alpha,double(app.trials{app.index}.EEG(2,:)));
            right_alpha=filtfilt(b_alpha,a_alpha,double(app.trials{app.index}.EEG(6,:)));
            [left_alpha_envelope, ignore]=envelope(left_alpha);
            [right_alpha_envelope, ignore]=envelope(right_alpha);
            
            
            for i_sample=1+window_size:step_size:size(app.trials{app.index}.EEG,2)-window_size
                memorization(end+1) = bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[4 8]) / ...
                    bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[30 45]);
                aw_index(end+1) = 0.01*bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]) - ...
                    0.01*bandpower(app.trials{app.index}.EEG(9,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]);
                %log(sum(left_alpha_envelope(i_sample-window_size:i_sample+window_size).^2)/window_size)-log(sum(right_alpha_envelope(i_sample-window_size:i_sample+window_size).^2)/window_size);
                %(bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]) - ...
                %bandpower(app.trials{app.index}.EEG(2,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]));%/...
                %%(bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]) + ...
                %%bandpower(app.trials{app.index}.EEG(2,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[8 13]));
                workload(end+1) = bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[4 8]) / ...
                    bandpower(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size),app.trials{app.index}.EEG_fs,[1 13]);
                val_left(end+1) = Higuchi_FD(app.trials{app.index}.EEG(1,i_sample-window_size:i_sample+window_size), 3);
                val_right(end+1) = Higuchi_FD(app.trials{app.index}.EEG(9,i_sample-window_size:i_sample+window_size), 3);
                arousal(end+1) = Higuchi_FD(app.trials{app.index}.EEG(12,i_sample-window_size:i_sample+window_size), 3);
                index_sample(end+1)=i_sample;
            end
            app.trials{app.index}.memorization=normalize(memorization);
            app.trials{app.index}.valence=normalize(val_left-val_right);
            app.trials{app.index}.arousal=normalize(arousal);
            app.trials{app.index}.aw_index=normalize(aw_index);
            app.trials{app.index}.workload=normalize(workload);
            app.trials{app.index}.eeg_index_sample=index_sample/app.trials{app.index}.EEG_fs;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%             ET             %%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function getROIlist(app, ROIlist)
            app.trials{app.index}.ROIs = ROIlist;
            app.updateScanPath();
            for i=1:length(ROIlist)
                rectangle(app.UIAxes,'Position',ROIlist{i})
                text(app.UIAxes,ROIlist{i}(1),ROIlist{i}(2),strcat('ROI: ',num2str(i)))
            end
        end
        
        function ET_cleaned=preprocessET(app)
            ET.time_series = app.trials{app.index}.ET;
            ET.time_stamps = [1:size(ET.time_series,2)]/app.trials{app.index}.ET_fs;
            
            %% X Coords
            
            left_nan_x = find(isnan(ET.time_series(1,:))==1);
            right_nan_x = find(isnan(ET.time_series(4,:))==1);
            
            both_nan_x = intersect(left_nan_x,right_nan_x);
            
            left_x_replace = setdiff(left_nan_x,both_nan_x);
            right_x_replace = setdiff(right_nan_x,both_nan_x);
            
            ET.time_series(1,left_x_replace) = ET.time_series(4,left_x_replace);
            ET.time_series(4,right_x_replace) = ET.time_series(1,right_x_replace);
            
            %% Y coords
            
            left_nan_y = find(isnan(ET.time_series(2,:))==1);
            right_nan_y = find(isnan(ET.time_series(5,:))==1);
            
            both_nan_y = intersect(left_nan_y,right_nan_y);
            
            left_y_replace = setdiff(left_nan_y,both_nan_y);
            right_y_replace = setdiff(right_nan_y,both_nan_y);
            
            ET.time_series(2,left_y_replace) = ET.time_series(5,left_y_replace);
            ET.time_series(5,right_y_replace) = ET.time_series(2,right_y_replace);
            
            %% Remove Closed Eyes
            left_x = squeeze(ET.time_series(1,:));
            left_x(both_nan_y)=[];
            
            right_x = squeeze(ET.time_series(4,:));
            right_x(both_nan_y)=[];
            
            XX = mean([left_x;right_x]);
            
            left_y = squeeze(ET.time_series(2,:));
            left_y(both_nan_y)=[];
            
            right_y = squeeze(ET.time_series(5,:));
            right_y(both_nan_y)=[];
            
            YY = mean([left_y;right_y]);
            
            ET.time_stamps(both_nan_y)=[];
            time=(ET.time_stamps-min(ET.time_stamps))*1000;
            ET_cleaned = [XX;YY;time];
        end
        
        function fixation_list=calculateFixationList(app, ET_cleaned)
            [fixation_list_t2,fixation_list_3s]=fixation_detection(ET_cleaned',0.250,0.100,200,0.6,1.4);
            
            fix_duration= round(fixation_list_t2(:,end));
            fix_dur_min = min(fix_duration);
            fix_dur_max = max(fix_duration);
            
            fix_duration_norm = ((fix_duration-fix_dur_min)/(fix_dur_max-fix_dur_min))+1;
            fixation_list.all= fixation_list_t2;
            fixation_list.durations=fix_duration;
            fixation_list.duration_norm=fix_duration_norm;
            fixation_list.durations=fix_duration;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadRecordingMenu
        function LoadRecordingMenuSelected(app, event)
            [file,path] = uigetfile('*.xdf');
            app.trial_segmentation(strcat(path,file))
            app.TrialsDropDown.Items={};
            for i_trial=1:size(app.trials,2)
                app.TrialsDropDown.Items{end+1} = strcat(num2str(i_trial),':',app.trials{i_trial}.Type);
            end
            app.ScanPathOptionsButton.Enable = 'on';
        end

        % Value changed function: TrialsDropDown
        function TrialsDropDownValueChanged(app, event)
            value = app.TrialsDropDown.Value;
            app.last_plot={};
            app.last_gaze_plot={};
            app.index = find(strcmp(app.TrialsDropDown.Items, value));
            app.I=imread(strcat('.\Images_new\',app.trials{app.index}.ID));
            app.PlayButtonCounter=0;
            app.PlayButton.Text='Play';
            
            [height, width, depth]=size(app.I);
            app.updateGazeHeatmap();
            app.updateScanPath();
            app.updateEEGMetrics()
            app.updateImage( app.UIAxes5, 'on');
            app.calculateTVEEGMetric()
            %plot(app.UIAxes4_1, app.trials{app.index}.eeg_index_sample,app.trials{app.index}.workload)
            app.UIAxes4_1.XLim=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.UIAxes4_2.XLim=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.UIAxes4_3.XLim=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.UIAxes4_4.XLim=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.UIAxes4_5.XLim=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.UIAxes5.XLim=[0 width];
            app.UIAxes5.YLim=[0 height];
            app.TimeSlider.Limits=[0 length(app.trials{app.index}.EEG)/app.trials{app.index}.EEG_fs];
            app.TimeSlider.Value=app.TimeSlider.Limits(1);
            app.plotTVEEGIndices();
            
        end

        % Button pushed function: ScanPathOptionsButton
        function ScanPathOptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ScanPathOptionsButton.Enable = 'off';
            ET_cleaned=app.preprocessET();
            fixation_list=app.calculateFixationList(ET_cleaned);
            % Open the options dialog and pass inputs
            app.DialogApp = box_scanpath(app, fixation_list,app.trials{app.index});
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Menu selected function: ROIManagementMenu
        function ROIManagementMenuSelected(app, event)
            app.ROIManagementMenu.Enable = 'off';
            app.DialogApp = ROImanager(app);
        end

        % Menu selected function: EEG2ETMenu
        function EEG2ETMenuSelected(app, event)
            app.EEG2ETMenu.Enable = 'off';
            app.DialogApp = EEG2ET(app);
        end

        % Menu selected function: ROIStatisticsMenu
        function ROIStatisticsMenuSelected(app, event)
            app.ROIStatisticsMenu.Enable = 'off';
            ET_cleaned=app.preprocessET();
            fixation_list=app.calculateFixationList(ET_cleaned);
            app.DialogApp = ROIstatistics(app, fixation_list);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            [height, width, depth]=size(app.I);
            %app.updateImage( app.UIAxes5, 'on');
            app.plotTVEEGIndices()
            ET_sample_end=max(1,round(changingValue*app.trials{app.index}.ET_fs));
            ET_sample_start=max(1,ET_sample_end-app.trials{app.index}.ET_fs);
            if ~isempty(app.last_plot)
                set(app.last_plot,'Visible','off')
            end
            if ~isempty(app.last_gaze_plot)
                set(app.last_gaze_plot,'Visible','off')
            end
            app.last_plot=plot(app.UIAxes5,mean(app.trials{app.index}.ET([1 4],ET_sample_start:ET_sample_end))*width,...
                mean(app.trials{app.index}.ET([2 5],ET_sample_start:ET_sample_end))*height,'g','LineWidth',2)
            for i=1:size(app.trials{app.index}.fixation_list.all,1)
                if changingValue>(app.trials{app.index}.fixation_list.all(i,5)/1000) & changingValue<(app.trials{app.index}.fixation_list.all(i,6)/1000)
                    %i
                    norm_value=1000000*(changingValue-app.trials{app.index}.fixation_list.all(i,5)/1000)/(app.trials{app.index}.fixation_list.all(i,6)*1000-app.trials{app.index}.fixation_list.all(i,5)*1000)
                    app.last_gaze_plot=plot(app.UIAxes5, app.trials{app.index}.fixation_list.all(i,1)*width,app.trials{app.index}.fixation_list.all(i,2)*height,'--go','MarkerSize',  ...
                        app.trials{app.index}.fixation_list.duration_norm(i)*20*norm_value, 'MarkerFaceColor',[.49 1 .63] ...
                        ),hold(app.UIAxes,'on')
                end
            end
            %             pupil_size_left = app.trials{app.index}.ET(3,ET_sample_start:ET_sample_end))
            %             plot(app.UIAxes6,1,1,'MarkerSize',app.trials{app.index}.ET(3,ET_sample_end),'ro')
            
            pupil_size_left = app.trials{app.index}.ET(3,:);
            pupil_size_left(isnan(pupil_size_left))=10;
            
            pupil_size_right = app.trials{app.index}.ET(6,:);
            pupil_size_right(isnan(pupil_size_right))=10;
            app.UIAxes6.XLim=[1 15];
            app.UIAxes6.cla;
            if pupil_size_left(ET_sample_end)==10
                plot(app.UIAxes6,5,2,'rx','MarkerSize',pupil_size_left(ET_sample_end)),hold(app.UIAxes6,'on')
            else
                %                 plot(app.UIAxes6,pupil_size_left)
                plot(app.UIAxes6,5,2,'go','MarkerFaceColor',[.49 1 .63],'MarkerSize',pupil_size_left(ET_sample_end)^3),hold(app.UIAxes6,'on')
                text(app.UIAxes6,4,1.5,num2str(pupil_size_left(ET_sample_end)))
            end
            
            if pupil_size_right(ET_sample_end)==10
                plot(app.UIAxes6,10,2,'rx','MarkerSize',pupil_size_right(ET_sample_end)),hold(app.UIAxes6,'on')
                
            else
                %                 plot(app.UIAxes6,pupil_size_left)
                plot(app.UIAxes6,10,2,'go','MarkerFaceColor',[.49 1 .63],'MarkerSize',pupil_size_right(ET_sample_end)^3),hold(app.UIAxes6,'on')
                text(app.UIAxes6,9,1.5,num2str(pupil_size_right(ET_sample_end)))
            end
            %             plot(app.UIAxes6,10,2,'go','MarkerSize',10*pupil_size_left(ET_sample_end)),hold(app.UIAxes6)
            %             plot(app.UIAxes6,11,2,'go','MarkerSize',10*pupil_size_right(ET_sample_end))%,hold(app.UIAxes6)
            %             plot(app.UIAxes6,pupil_size_left)
            xline(app.UIAxes4_1,changingValue)
            xline(app.UIAxes4_2,changingValue)
            xline(app.UIAxes4_3,changingValue)
            xline(app.UIAxes4_4,changingValue)
            xline(app.UIAxes4_5,changingValue)
            drawnow
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButtonCounter=app.PlayButtonCounter+1;
            app.PlayButton.Text='Pause';
            for changingValue=app.TimeSlider.Value:0.1:app.TimeSlider.Limits(end)
                app.TimeSlider.Value=changingValue;
                if ~mod(app.PlayButtonCounter,2)
                    app.PlayButton.Text='Play';
                    break;
                end
                %app.TrialsDropDown.Value=changingValue;
                [height, width, depth]=size(app.I);
                %app.updateImage( app.UIAxes5, 'on');
                app.plotTVEEGIndices()
                ET_sample_end=round(changingValue*app.trials{app.index}.ET_fs);
                ET_sample_start=max(1,ET_sample_end-app.trials{app.index}.ET_fs);
                if ~isempty(app.last_plot)
                    set(app.last_plot,'Visible','off')
                end
                if ~isempty(app.last_gaze_plot)
                    set(app.last_gaze_plot,'Visible','off')
                end
                
                app.last_plot=plot(app.UIAxes5, fillmissing(mean(app.trials{app.index}.ET([1 4],ET_sample_start:ET_sample_end)),'linear')*width,...
                    fillmissing(mean(app.trials{app.index}.ET([2 5],ET_sample_start:ET_sample_end)),'linear')*height,'g','LineWidth',2)
                for i=1:size(app.trials{app.index}.fixation_list.all,1)
                    if changingValue>(app.trials{app.index}.fixation_list.all(i,5)/1000) & changingValue<(app.trials{app.index}.fixation_list.all(i,6)/1000)
                        %i
                        norm_value=1000000*(changingValue-app.trials{app.index}.fixation_list.all(i,5)/1000)/(app.trials{app.index}.fixation_list.all(i,6)*1000-app.trials{app.index}.fixation_list.all(i,5)*1000)
                        app.last_gaze_plot=plot(app.UIAxes5, app.trials{app.index}.fixation_list.all(i,1)*width,app.trials{app.index}.fixation_list.all(i,2)*height,'--go','MarkerSize',  ...
                            app.trials{app.index}.fixation_list.duration_norm(i)*20*norm_value, 'MarkerFaceColor',[.49 1 .63] ...
                            ),hold(app.UIAxes,'on')
                    end
                end
                xline(app.UIAxes4_1,changingValue)
                xline(app.UIAxes4_2,changingValue)
                xline(app.UIAxes4_3,changingValue)
                drawnow
                pause(0.05)
            end
        end

        % Callback function
        function PlaySwitchValueChanged(app, event)
            value = app.PlaySwitch.Value;
            for changingValue=app.TimeSlider.Limits(1):0.1:app.TimeSlider.Limits(end)
                if strcmp(value, 'On')
                    break;
                end
                %app.TrialsDropDown.Value=changingValue;
                [height, width, depth]=size(app.I);
                %app.updateImage( app.UIAxes5, 'on');
                app.plotTVEEGIndices()
                ET_sample_end=round(changingValue*app.trials{app.index}.ET_fs);
                ET_sample_start=max(1,ET_sample_end-app.trials{app.index}.ET_fs);
                if ~isempty(app.last_plot)
                    set(app.last_plot,'Visible','off')
                end
                if ~isempty(app.last_gaze_plot)
                    set(app.last_gaze_plot,'Visible','off')
                end
                
                app.last_plot=plot(app.UIAxes5, fillmissing(mean(app.trials{app.index}.ET([1 4],ET_sample_start:ET_sample_end)),'linear')*width,...
                    fillmissing(mean(app.trials{app.index}.ET([2 5],ET_sample_start:ET_sample_end)),'linear')*height,'g','LineWidth',2)
                for i=1:size(app.trials{app.index}.fixation_list.all,1)
                    if changingValue>(app.trials{app.index}.fixation_list.all(i,5)/1000) & changingValue<(app.trials{app.index}.fixation_list.all(i,6)/1000)
                        %i
                        norm_value=1000000*(changingValue-app.trials{app.index}.fixation_list.all(i,5)/1000)/(app.trials{app.index}.fixation_list.all(i,6)*1000-app.trials{app.index}.fixation_list.all(i,5)*1000)
                        app.last_gaze_plot=plot(app.UIAxes5, app.trials{app.index}.fixation_list.all(i,1)*width,app.trials{app.index}.fixation_list.all(i,2)*height,'--go','MarkerSize',  ...
                            app.trials{app.index}.fixation_list.duration_norm(i)*20*norm_value, 'MarkerFaceColor',[.49 1 .63] ...
                            ),hold(app.UIAxes,'on')
                    end
                end
                xline(app.UIAxes4_1,changingValue)
                xline(app.UIAxes4_2,changingValue)
                xline(app.UIAxes4_3,changingValue)
                drawnow
                pause(0.05)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.LoadRecordingMenu = uimenu(app.FIleMenu);
            app.LoadRecordingMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRecordingMenuSelected, true);
            app.LoadRecordingMenu.Text = 'Load Recording';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.TrialSegmentationMenu = uimenu(app.EditMenu);
            app.TrialSegmentationMenu.Text = 'Trial Segmentation';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.LogFileMenu = uimenu(app.HelpMenu);
            app.LogFileMenu.Text = 'Log File';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ROIManagementMenu = uimenu(app.ToolsMenu);
            app.ROIManagementMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIManagementMenuSelected, true);
            app.ROIManagementMenu.Text = 'ROI Management';
            app.ROIStatisticsMenu = uimenu(app.ToolsMenu);
            app.ROIStatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIStatisticsMenuSelected, true);
            app.ROIStatisticsMenu.Text = 'ROI Statistics';
            app.EEG2ETMenu = uimenu(app.ToolsMenu);
            app.EEG2ETMenu.MenuSelectedFcn = createCallbackFcn(app, @EEG2ETMenuSelected, true);
            app.EEG2ETMenu.Text = 'EEG2ET';
            app.TrialsDropDownLabel = uilabel(app.UIFigure);
            app.TrialsDropDownLabel.Text = 'Trials';
            app.TrialsDropDown = uidropdown(app.UIFigure);
            app.TrialsDropDown.Items = {};
            app.TrialsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialsDropDownValueChanged, true);
            app.TrialsDropDown.Value = {};
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ScanPath - whole trial')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'HeatMap - whole trial')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'EEG Indices')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ScanPathOptionsButton = uibutton(app.UIFigure, 'push');
            app.ScanPathOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanPathOptionsButtonPushed, true);
            app.ScanPathOptionsButton.Text = 'ScanPath Options';
            app.UIAxes4_1 = uiaxes(app.UIFigure);
            title(app.UIAxes4_1, 'A-W index')
            xlabel(app.UIAxes4_1, 'Time (seconds)')
            ylabel(app.UIAxes4_1, 'Level')
            app.UIAxes4_2 = uiaxes(app.UIFigure);
            title(app.UIAxes4_2, 'Mental Workload')
            xlabel(app.UIAxes4_2, 'Time (seconds)')
            ylabel(app.UIAxes4_2, 'Level')
            app.UIAxes4_3 = uiaxes(app.UIFigure);
            title(app.UIAxes4_3, 'Memorization')
            xlabel(app.UIAxes4_3, 'Time (seconds)')
            ylabel(app.UIAxes4_3, 'Level')
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Live Gaze Viewer')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTick = [];
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Pupil Dilation')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes4_4 = uiaxes(app.UIFigure);
            title(app.UIAxes4_4, 'Valence')
            xlabel(app.UIAxes4_4, 'Time (seconds)')
            ylabel(app.UIAxes4_4, 'Level')
            app.UIAxes4_5 = uiaxes(app.UIFigure);
            title(app.UIAxes4_5, 'Arousal')
            xlabel(app.UIAxes4_5, 'Time (seconds)')
            ylabel(app.UIAxes4_5, 'Level')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard_DSI24

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end